classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        ClickImageButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Video = load('video_test.mat');
        vid;
        videoInputNumber = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mkdir 'SessionData'
            %app.vid = videoinput('winvideo', app.videoInputNumber);
            app.vid = videoinput('pointgrey', 2, 'F7_Raw16_1024x768_Mode2');
            
%             src = getselectedsource(app.vid); 
%             src.ExposureMode = app.Video.ExposureMode; 
%             src.FrameRateMode = app.Video.FrameRateMode;
%             src.ShutterMode = app.Video.ShutterMode;
%             src.Gain = app.Video.Gain;
%             src.Gamma = app.Video.Gamma;
%             src.Brightness = app.Video.Brightness;
%             src.Shutter = app.Video.ExposureTime;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
            
            f = figure;%('Visible', 'off');
            pImage = imshow(uint16(zeros(imageRes)));
            
            axis image
            setappdata(pImage, 'UpdatePreviewWindowFcn', @ba_pulloffview)
            p = preview(app.vid, pImage);
            set(p, 'CDataMapping', 'scaled');
            
        end

        % Button pushed function: ClickImageButton
        function ClickImageButtonPushed(app, event)
            img = getsnapshot(app.vid);
            imshow(img, 'Parent', app.UIAxes);
            imwrite(img,'SessionData\test.jpg')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ClickImageButton = uibutton(app.UIFigure, 'push');
            app.ClickImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClickImageButtonPushed, true);
            app.ClickImageButton.Text = 'Click Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end